classdef Lap_sim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CFSLaptimeSimulatorUIFigure     matlab.ui.Figure
        TrackselectionPanel             matlab.ui.container.Panel
        TracksDropDownLabel             matlab.ui.control.Label
        Trackslist                      matlab.ui.control.DropDown
        Loadtrack                       matlab.ui.control.Button
        trackmap                        matlab.ui.control.UIAxes
        VehicleparametersPanel          matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ChassisTab                      matlab.ui.container.Tab
        WheelbaseEditFieldLabel         matlab.ui.control.Label
        Wheelbase                       matlab.ui.control.NumericEditField
        TrackwidthfrontEditFieldLabel   matlab.ui.control.Label
        Trackwidthfront                 matlab.ui.control.NumericEditField
        TrackwidthRearEditFieldLabel    matlab.ui.control.Label
        TrackwidthRear                  matlab.ui.control.NumericEditField
        VehicleCOGEditFieldLabel        matlab.ui.control.Label
        VehicleCOG_x                    matlab.ui.control.NumericEditField
        CarweightEditFieldLabel         matlab.ui.control.Label
        Carweight                       matlab.ui.control.NumericEditField
        DriversweightEditFieldLabel     matlab.ui.control.Label
        Driversweight                   matlab.ui.control.NumericEditField
        VehicleCOG_y                    matlab.ui.control.NumericEditField
        VehicleCOG_z                    matlab.ui.control.NumericEditField
        DriverCOG_y                     matlab.ui.control.NumericEditField
        DriverCOG_z                     matlab.ui.control.NumericEditField
        DriverCOGEditFieldLabel         matlab.ui.control.Label
        DriverCOG_x                     matlab.ui.control.NumericEditField
        SuspensionTab                   matlab.ui.container.Tab
        SpringrateEditFieldLabel        matlab.ui.control.Label
        Springrate_front                matlab.ui.control.NumericEditField
        Springrate_rear                 matlab.ui.control.NumericEditField
        FrontLabel                      matlab.ui.control.Label
        RearLabel                       matlab.ui.control.Label
        Unsprungweight_rear             matlab.ui.control.NumericEditField
        UnsprungweightEditFieldLabel    matlab.ui.control.Label
        Unsprungweight_front            matlab.ui.control.NumericEditField
        RearLabel_3                     matlab.ui.control.Label
        FrontLabel_3                    matlab.ui.control.Label
        UnsprungHeight_rear             matlab.ui.control.NumericEditField
        RearLabel_4                     matlab.ui.control.Label
        FrontLabel_4                    matlab.ui.control.Label
        UnsprungHeightEditFieldLabel    matlab.ui.control.Label
        UnsprungHeight_front            matlab.ui.control.NumericEditField
        Rollcenter_rear                 matlab.ui.control.NumericEditField
        RearLabel_5                     matlab.ui.control.Label
        FrontLabel_5                    matlab.ui.control.Label
        RollcenterEditFieldLabel        matlab.ui.control.Label
        Rollcenter_front                matlab.ui.control.NumericEditField
        Pitchcenter_rear                matlab.ui.control.NumericEditField
        RearLabel_6                     matlab.ui.control.Label
        FrontLabel_6                    matlab.ui.control.Label
        PitchcenterEditFieldLabel       matlab.ui.control.Label
        Pitchcenter_front               matlab.ui.control.NumericEditField
        Antirollbarstiffness_rear       matlab.ui.control.NumericEditField
        FrontLabel_7                    matlab.ui.control.Label
        RearLabel_7                     matlab.ui.control.Label
        AntirollbarstiffnessEditField_7Label  matlab.ui.control.Label
        Antirollbarstiffness_front      matlab.ui.control.NumericEditField
        BrakeinertiaEditFieldLabel      matlab.ui.control.Label
        Brakeinertia                    matlab.ui.control.NumericEditField
        TireTab                         matlab.ui.container.Tab
        TireradiusEditFieldLabel        matlab.ui.control.Label
        Tireradius                      matlab.ui.control.NumericEditField
        TireStiffnessEditFieldLabel     matlab.ui.control.Label
        TireStiffness                   matlab.ui.control.NumericEditField
        RollingresistanceEditFieldLabel  matlab.ui.control.Label
        Rollingresistance               matlab.ui.control.NumericEditField
        RotationalInertiaEditFieldLabel  matlab.ui.control.Label
        RotationalInertia               matlab.ui.control.NumericEditField
        GripfactorEditFieldLabel        matlab.ui.control.Label
        Gripfactor                      matlab.ui.control.NumericEditField
        TireLabel                       matlab.ui.control.Label
        TiresdataButtonGroup            matlab.ui.container.ButtonGroup
        Hoosier13Button                 matlab.ui.control.RadioButton
        Hoosier10Button                 matlab.ui.control.RadioButton
        AeroTab                         matlab.ui.container.Tab
        DragcoefficientEditFieldLabel   matlab.ui.control.Label
        Dragcoefficient                 matlab.ui.control.NumericEditField
        LiftcoefficientEditFieldLabel   matlab.ui.control.Label
        Liftcoefficient                 matlab.ui.control.NumericEditField
        CrosssectionareaEditFieldLabel  matlab.ui.control.Label
        Crosssectionarea                matlab.ui.control.NumericEditField
        AirdensityEditFieldLabel        matlab.ui.control.Label
        Airdensity                      matlab.ui.control.NumericEditField
        CenterofpressureEditFieldLabel  matlab.ui.control.Label
        COP_y                           matlab.ui.control.NumericEditField
        COP_x                           matlab.ui.control.NumericEditField
        COP_z                           matlab.ui.control.NumericEditField
        PowertrainTab                   matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        MotorTab                        matlab.ui.container.Tab
        EfficiencyEditFieldLabel        matlab.ui.control.Label
        motorEfficiency                 matlab.ui.control.NumericEditField
        PeakTorqueEditFieldLabel        matlab.ui.control.Label
        BatteryPeakTorque               matlab.ui.control.NumericEditField
        RPMLimitEditFieldLabel          matlab.ui.control.Label
        BatteryRPMLimit                 matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        WDButton                        matlab.ui.control.RadioButton
        WDButton_2                      matlab.ui.control.RadioButton
        motormap                        matlab.ui.control.UIAxes
        loadmap                         matlab.ui.control.Button
        BatteryTab                      matlab.ui.container.Tab
        CapacityEditFieldLabel          matlab.ui.control.Label
        BatteryCapacity                 matlab.ui.control.NumericEditField
        NominalVoltageEditFieldLabel    matlab.ui.control.Label
        BatteryNominalVoltage           matlab.ui.control.NumericEditField
        ResistanceEditFieldLabel        matlab.ui.control.Label
        BatteryResistance               matlab.ui.control.NumericEditField
        EfficiencyEditField_2Label      matlab.ui.control.Label
        BatteryEfficiency               matlab.ui.control.NumericEditField
        DrivelineTab                    matlab.ui.container.Tab
        EfficiencyEditField_3Label      matlab.ui.control.Label
        DrivelineEfficiency             matlab.ui.control.NumericEditField
        DrivelineinertiaEditFieldLabel  matlab.ui.control.Label
        Drivelineinertia                matlab.ui.control.NumericEditField
        GearratioEditFieldLabel         matlab.ui.control.Label
        Gearratio                       matlab.ui.control.NumericEditField
        FinaldriveratioEditFieldLabel   matlab.ui.control.Label
        Finaldriveratio                 matlab.ui.control.NumericEditField
        PostprocessorPanel              matlab.ui.container.Panel
        postpro                         matlab.ui.control.UIAxes
        XaxisListBoxLabel               matlab.ui.control.Label
        XaxisListBox                    matlab.ui.control.ListBox
        PlotButton                      matlab.ui.control.Button
        YaxisListBoxLabel               matlab.ui.control.Label
        YaxisListBox                    matlab.ui.control.ListBox
        RunsimulationButton             matlab.ui.control.Button
        TotalLaptimeEditFieldLabel      matlab.ui.control.Label
        TotalLaptime                    matlab.ui.control.NumericEditField
        AverageLatGsEditFieldLabel      matlab.ui.control.Label
        AverageLatGs                    matlab.ui.control.NumericEditField
        AverageLongGsEditFieldLabel     matlab.ui.control.Label
        AverageLongGs                   matlab.ui.control.NumericEditField
        TotalPointsEditFieldLabel       matlab.ui.control.Label
        TotalPoints                     matlab.ui.control.NumericEditField
        SaveButton                      matlab.ui.control.StateButton
        LoadButton                      matlab.ui.control.StateButton
        LaptimesimulatorV10Button       matlab.ui.control.Button
    end

    
    properties (Access = public)
        track % Description
        Tele
        car
        CarTire
    end 
    
    methods (Access = private)
        
        function [C] = carbuilder(app)
            %Function of this script is to get the parameters
            
            % Chassis parameters
            
             CarCG = [app.VehicleCOG_x.Value	app.VehicleCOG_y.Value	app.VehicleCOG_z.Value]; % x y z (mm)
             Wheelbase = app.Wheelbase.Value; % mm
            TrackWidth = [app.Trackwidthfront.Value app.TrackwidthRear.Value]; % Front Back (mm)
            DriverWeight = app.Driversweight.Value; % kg
            DriverCG = [app.DriverCOG_x.Value	app.DriverCOG_y.Value	app.DriverCOG_z.Value]; % x y z (mm)
            CarWeight =  app.Carweight.Value; % Kg
            % Suspension Parameters
            
            SpringRate = [app.Springrate_front.Value app.Springrate_rear.Value]; % Front Rear (N/mm)
            ARBRate = [app.Antirollbarstiffness_front.Value app.Antirollbarstiffness_rear.Value]; % Front Rear (in N/deg)
            UnsprungW = [app.Unsprungweight_front.Value app.Unsprungweight_rear.Value]; % Front  (Kg)
            UnsprungH = [app.UnsprungHeight_front.Value app.UnsprungHeight_rear.Value]; % unsprung cg height  (mm)
            RollCenter = [app.Rollcenter_front.Value app.Rollcenter_rear.Value]; % Front Back (mm)
            PitchCenter = [app.Pitchcenter_front.Value app.Pitchcenter_rear.Value]; % Height Distance from back (mm)
            
            %"Carsuspension" to store all the suspension parameters in one object
            Suspension = CarSuspension(SpringRate,ARBRate,UnsprungW,UnsprungH,RollCenter,PitchCenter);
            
            % Tire Parameters
            
            K = app.TireStiffness.Value; % N/mm
            R = app.Tireradius.Value; % mm
            RollingResistance = app.Rollingresistance.Value;
            J_t = app.RotationalInertia.Value; % Kg m2
            Gripfactor = app.Gripfactor.Value;
            if app.Hoosier13Button.Value==1
                TireModel = @Hoosier13;
            else
                TireModel = @Hoosier10;
            end
            %"CarTire" to store all the suspension parameters in one object
            Tire = CarTire(TireModel,K,R,RollingResistance,J_t,Gripfactor);
            
            % Brake Parameters
            J_b = app.Brakeinertia.Value; % Kg m2
            Brakes = CarBrakes(J_b);
            
            % Aero Parameters
            Drag = app.Dragcoefficient.Value;
            Lift = app.Liftcoefficient.Value;
            CrossArea =app.Crosssectionarea.Value;  % m^2
            rho = app.Airdensity.Value ;
            cop = [app.COP_x.Value app.COP_y.Value app.COP_z.Value];
            
            %% MOtor
            
            Efficiency = app.motorEfficiency.Value;
            PeakTorque = app.BatteryPeakTorque.Value; % Nm
            PowerLimit = 80 * Efficiency; % kW
            RPMLimit = app.BatteryRPMLimit.Value;
            PowerScaler = 1 / 9549; % Nm * RPM * PowerScaler = kW
            
            RPMS = (0:RPMLimit)';
            Torque = ones(RPMLimit + 1, 1) * PeakTorque;
            
            for i = 1:RPMLimit+1
                if Torque(i) * RPMS(i) * PowerScaler > PowerLimit
                    Torque(i) = PowerLimit / (RPMS(i) * PowerScaler);
                end
            end
            
            Torque(RPMLimit+1) = 0;
            EfficiencyCurve = ones(length(RPMS),1)*Efficiency;
            OutputCurve = [RPMS,Torque,EfficiencyCurve];
            NMotors = 1;%% Doesnt matter
            Motor = CarMotor(OutputCurve,NMotors);
            plot(app.motormap,OutputCurve(:,1),OutputCurve(:,2),'linewidth',2)
            
            %% Battery Parameters
            
            Capacity = app.BatteryCapacity.Value; % kWh
            NominalVoltage = app.BatteryNominalVoltage.Value; % V
            Resistance = app.BatteryResistance.Value; % ohms
            RoundtripEfficiency =app.BatteryEfficiency.Value;
            Battery = CarBattery(Capacity,Resistance,NominalVoltage,RoundtripEfficiency);
            
            %% Driveline
            
            Efficiency = app.DrivelineEfficiency.Value;
            J_d = app.Drivelineinertia.Value; %
            Tmult = 1; % torque multiplier
            GearRatios =app.Gearratio.Value;
            FinalDrive =app.Finaldriveratio.Value;
            FinalDriveRatio = Tmult*GearRatios*FinalDrive;
            Driveline = CarDriveline(GearRatios,Efficiency,J_d,FinalDriveRatio,Motor.OutputCurve);
            
            %% Car Parameters
            
            C = Car(Brakes,Driveline,Motor,Battery,Suspension,Tire,CrossArea,Lift,Drag,rho,cop,CarWeight,CarCG,DriverWeight,DriverCG,TrackWidth,Wheelbase);
            C.BrakingMode = 'Hydraulic';
            
            %% Tire property
            
            function [ Force,Slip ] = Hoosier13( Normal,LongLat)
                % These equations of based of curve fits of Hoosier R25B 13x7 tire data.
                % * Fit is based on tire normal loads from 0-350lbs and is only an
                %   extrapolation beyond that.
                % * Optimum Slip Angles/Ratios were used to find the ouput force and are
                %   returned. Returned Slip Angle may be inaccurate at normal loads < 50 lbs.
                % * An Inclination Angle of 0 was also used.
                % * Source Data:
                %       Longitudinal: https://docs.google.com/spreadsheets/d/1GGr0C9T5nTWj2hsEe3SVV6OE34LaEc_uqaYXwbKgMGM/edit?usp=sharing
                %       Lateral: https://docs.google.com/spreadsheets/d/1Gx2vhS-jnTdLmaWeMJVq3nm5NF3w7Ltedh8BYTxcZX4/edit?usp=sharing
                
                if strcmp(LongLat,'Lateral')
                    Force = -0.001835 * Normal .^ 2 + 2.014 * Normal + -0.002824;
                    Slip = 2.51e-13 * Normal .^ 5 + -9.44e-11 * Normal .^ 4 + -9.74e-08 * Normal .^ 3 + 6.824e-5 * Normal .^ 2 + -0.003949 * Normal + 3.371;
                elseif strcmp(LongLat,'Longitudinal')
                    Force = -0.001004 * Normal .^ 2 + 1.801 * Normal + .08458;
                    Slip = 3.913e-08 * Normal .^ 3 + 4.941e-06 * Normal .^ 2 + .0003835 * Normal + 3.719;
                else
                    Force = [];
                    Slip = [];
                end
                
                % Handle normal loads of 0 or below.
                Force(Normal <= 0) = 0.0;
                Slip(Normal <= 0) = 0.0;
                
            end
            
            function [ Coeff,SA ] = Hoosier10( Normal,LongLat )
                
                if strcmp(LongLat,'Lateral')
                    
                    Fz = -[0;-3.53535400000000;-7.07070800000000;-10.6060600000000;-14.1414200000000;-17.6767700000000;-21.2121300000000;-24.7474800000000;-28.2828300000000;-31.8181900000000];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
                    Grip = [0,-1.39692400000000,-2.76310400000000,-4.08350400000000,-5.34421500000000,-6.53264200000000,-7.63762900000000,-8.64956200000000,-9.56046300000000,-10.3640000000000,-11.0555200000000,-11.6319800000000,-12.0919100000000,-12.4353400000000,-12.6636800000000,-12.7795900000000,-12.7868900000000,-12.6903300000000,-12.4955800000000,-12.2089900000000,-11.8374500000000,-11.3883500000000,-10.8693700000000,-10.2884000000000,-9.65344000000000,-8.97253900000000,-8.25365800000000,-7.50464600000000,-6.73318700000000,-5.94673200000000,-5.15245600000000,-4.35728700000000,-3.56780100000000,-2.79029400000000,-2.03070600000000,-1.29465100000000,-0.587425600000000,0.0860339800000000,0.721063400000000,1.31335500000000,1.85887800000000,2.35388900000000,2.79491500000000,3.17877000000000,3.50248700000000,3.76334500000000,3.95883400000000,4.08664600000000,4.14469200000000,4.13104200000000,4.04392700000000,3.88173900000000,3.64303000000000,3.32646300000000,2.93084000000000,2.45506700000000,1.89816400000000,1.25924100000000,0.537498900000000,-0.267776100000000,-1.15722200000000,-2.13141500000000,-3.19086200000000,-4.33600000000000,-5.56724500000000,-6.88493200000000,-8.28936600000000,-9.78081200000000,-11.3594800000000,-13.0256000000000,-14.7792900000000,-16.6207200000000,-18.5499600000000,-20.5671300000000,-22.6722700000000,-24.8654500000000,-27.1466600000000,-29.5159600000000,-31.9733200000000,-34.5187100000000,-37.1521600000000,-39.8735900000000,-42.6829800000000,-45.5802500000000,-48.5653700000000,-51.6382800000000,-54.7988900000000,-58.0471000000000,-61.3829000000000,-64.8061700000000,-68.3167900000000,-71.9147300000000,-75.5998900000000,-79.3721500000000,-83.2314100000000,-87.1776100000000,-91.2106600000000,-95.3304100000000,-99.5367800000000,-103.829700000000;0,-2.45291900000000,-4.87177900000000,-7.23778000000000,-9.53334000000000,-11.7423200000000,-13.8501600000000,-15.8440900000000,-17.7131700000000,-19.4484000000000,-21.0427300000000,-22.4910400000000,-23.7900300000000,-24.9383300000000,-25.9361500000000,-26.7853000000000,-27.4890500000000,-28.0518900000000,-28.4794100000000,-28.7782100000000,-28.9556100000000,-29.0195900000000,-28.9786300000000,-28.8415200000000,-28.6173200000000,-28.3151900000000,-27.9443100000000,-27.5138000000000,-27.0326400000000,-26.5096200000000,-25.9532300000000,-25.3717400000000,-24.7730600000000,-24.1647700000000,-23.5540700000000,-22.9478100000000,-22.3524900000000,-21.7742000000000,-21.2186700000000,-20.6913000000000,-20.1971100000000,-19.7408100000000,-19.3267700000000,-18.9590200000000,-18.6413800000000,-18.3772900000000,-18.1699900000000,-18.0224700000000,-17.9374500000000,-17.9174600000000,-17.9648100000000,-18.0816300000000,-18.2698600000000,-18.5312900000000,-18.8675100000000,-19.2800400000000,-19.7702000000000,-20.3392300000000,-20.9882400000000,-21.7182500000000,-22.5301400000000,-23.4247500000000,-24.4028200000000,-25.4650100000000,-26.6119200000000,-27.8440800000000,-29.1619500000000,-30.5659800000000,-32.0565000000000,-33.6338700000000,-35.2983600000000,-37.0502100000000,-38.8896300000000,-40.8168200000000,-42.8319200000000,-44.9350700000000,-47.1263300000000,-49.4058400000000,-51.7736100000000,-54.2297000000000,-56.7741600000000,-59.4069800000000,-62.1281700000000,-64.9376900000000,-67.8355500000000,-70.8216900000000,-73.8961000000000,-77.0586900000000,-80.3094500000000,-83.6482900000000,-87.0751200000000,-90.5899100000000,-94.1925800000000,-97.8830300000000,-101.661100000000,-105.526900000000,-109.480200000000,-113.520900000000,-117.649000000000,-121.864300000000;0,-3.48550800000000,-6.93365100000000,-10.3221500000000,-13.6300000000000,-16.8377500000000,-19.9277100000000,-22.8841000000000,-25.6932300000000,-28.3435500000000,-30.8257000000000,-33.1325600000000,-35.2590800000000,-37.2024200000000,-38.9616000000000,-40.5375700000000,-41.9329600000000,-43.1518600000000,-44.1997700000000,-45.0833900000000,-45.8103400000000,-46.3891000000000,-46.8288200000000,-47.1390600000000,-47.3298300000000,-47.4113500000000,-47.3938500000000,-47.2876700000000,-47.1029700000000,-46.8497900000000,-46.5379100000000,-46.1768900000000,-45.7758300000000,-45.3436100000000,-44.8886600000000,-44.4189900000000,-43.9423000000000,-43.4657600000000,-42.9962200000000,-42.5400900000000,-42.1034100000000,-41.6918000000000,-41.3105500000000,-40.9645500000000,-40.6583900000000,-40.3963200000000,-40.1822800000000,-40.0199200000000,-39.9126200000000,-39.8634800000000,-39.8753700000000,-39.9510000000000,-40.0926900000000,-40.3027800000000,-40.5832600000000,-40.9360300000000,-41.3628000000000,-41.8651400000000,-42.4444800000000,-43.1021300000000,-43.8392800000000,-44.6570000000000,-45.5562700000000,-46.5379600000000,-47.6029000000000,-48.7517900000000,-49.9852600000000,-51.3039300000000,-52.7082900000000,-54.1988200000000,-55.7759200000000,-57.4399500000000,-59.1912500000000,-61.0300600000000,-62.9566700000000,-64.9712800000000,-67.0740400000000,-69.2651100000000,-71.5446500000000,-73.9127200000000,-76.3694200000000,-78.9148300000000,-81.5489600000000,-84.2718400000000,-87.0835100000000,-89.9839700000000,-92.9731800000000,-96.0511200000000,-99.2177700000000,-102.473100000000,-105.817100000000,-109.249600000000,-112.770600000000,-116.380100000000,-120.077900000000,-123.864000000000,-127.738400000000,-131.700800000000,-135.751300000000,-139.889700000000;0,-4.49011900000000,-8.93951600000000,-13.3227300000000,-17.6156900000000,-21.7959000000000,-25.8428000000000,-29.7378500000000,-33.4648200000000,-37.0097500000000,-40.3611800000000,-43.5099900000000,-46.4494900000000,-49.1753500000000,-51.6854500000000,-53.9797300000000,-56.0601600000000,-57.9303700000000,-59.5956300000000,-61.0626000000000,-62.3390800000000,-63.4339300000000,-64.3568200000000,-65.1179800000000,-65.7282200000000,-66.1986200000000,-66.5404900000000,-66.7651300000000,-66.8838400000000,-66.9078700000000,-66.8481100000000,-66.7153200000000,-66.5198600000000,-66.2717200000000,-65.9805200000000,-65.6554700000000,-65.3053100000000,-64.9383600000000,-64.5625100000000,-64.1851700000000,-63.8133500000000,-63.4536300000000,-63.1121500000000,-62.7946900000000,-62.5065800000000,-62.2528600000000,-62.0382300000000,-61.8669200000000,-61.7430400000000,-61.6702300000000,-61.6519400000000,-61.6914000000000,-61.7914600000000,-61.9548800000000,-62.1840800000000,-62.4814000000000,-62.8488800000000,-63.2884600000000,-63.8019200000000,-64.3908700000000,-65.0567200000000,-65.8008700000000,-66.6245300000000,-67.5287700000000,-68.5146600000000,-69.5830500000000,-70.7348300000000,-71.9707000000000,-73.2913500000000,-74.6974000000000,-76.1893700000000,-77.7677400000000,-79.4329600000000,-81.1854100000000,-83.0254400000000,-84.9533300000000,-86.9693400000000,-89.0737000000000,-91.2666100000000,-93.5482000000000,-95.9186700000000,-98.3780900000000,-100.926600000000,-103.564200000000,-106.290900000000,-109.106900000000,-112.012100000000,-115.006600000000,-118.090300000000,-121.263200000000,-124.525300000000,-127.876500000000,-131.316900000000,-134.846300000000,-138.464800000000,-142.172100000000,-145.968400000000,-149.853400000000,-153.827100000000,-157.889400000000;0,-5.46233900000000,-10.8804800000000,-16.2261600000000,-21.4725500000000,-26.5945200000000,-31.5689000000000,-36.3747100000000,-40.9933700000000,-45.4087900000000,-49.6074600000000,-53.5784500000000,-57.3134700000000,-60.8067400000000,-64.0549300000000,-67.0570400000000,-69.8142200000000,-72.3295400000000,-74.6079300000000,-76.6559000000000,-78.4812800000000,-80.0931400000000,-81.5015100000000,-82.7171600000000,-83.7515500000000,-84.6164800000000,-85.3241100000000,-85.8867000000000,-86.3165700000000,-86.6259700000000,-86.8268500000000,-86.9311300000000,-86.9502000000000,-86.8952300000000,-86.7769300000000,-86.6056100000000,-86.3911000000000,-86.1427200000000,-85.8693900000000,-85.5795700000000,-85.2811500000000,-84.9816300000000,-84.6880500000000,-84.4069600000000,-84.1445900000000,-83.9066500000000,-83.6985200000000,-83.5252700000000,-83.3914300000000,-83.3013900000000,-83.2591600000000,-83.2683900000000,-83.3325600000000,-83.4548000000000,-83.6380200000000,-83.8849700000000,-84.1980200000000,-84.5795600000000,-85.0315900000000,-85.5560900000000,-86.1548200000000,-86.8293200000000,-87.5811200000000,-88.4115400000000,-89.3218400000000,-90.3131000000000,-91.3863300000000,-92.5424600000000,-93.7823300000000,-95.1066800000000,-96.5161900000000,-98.0115000000000,-99.5931100000000,-101.261600000000,-103.017300000000,-104.860600000000,-106.791900000000,-108.811600000000,-110.919800000000,-113.116700000000,-115.402700000000,-117.777800000000,-120.242300000000,-122.796100000000,-125.439500000000,-128.172400000000,-130.995000000000,-133.907300000000,-136.909300000000,-140.001000000000,-143.182400000000,-146.453600000000,-149.814400000000,-153.264800000000,-156.804800000000,-160.434400000000,-164.153400000000,-167.961900000000,-171.859700000000,-175.846800000000;0,-6.39792900000000,-12.7480100000000,-19.0196200000000,-25.1835300000000,-31.2123600000000,-37.0806900000000,-42.7654200000000,-48.2459300000000,-53.5041400000000,-58.5247200000000,-63.2950900000000,-67.8053800000000,-72.0484400000000,-76.0197200000000,-79.7171900000000,-83.1411400000000,-86.2939500000000,-89.1801200000000,-91.8058300000000,-94.1788100000000,-96.3081500000000,-98.2040900000000,-99.8777500000000,-101.341100000000,-102.606500000000,-103.686800000000,-104.595000000000,-105.344400000000,-105.948000000000,-106.418900000000,-106.769900000000,-107.013500000000,-107.161800000000,-107.226600000000,-107.219200000000,-107.150500000000,-107.030800000000,-106.870000000000,-106.677500000000,-106.462100000000,-106.232200000000,-105.995700000000,-105.759900000000,-105.531900000000,-105.318200000000,-105.124800000000,-104.957400000000,-104.821300000000,-104.721400000000,-104.662300000000,-104.648200000000,-104.683000000000,-104.770400000000,-104.913700000000,-105.116000000000,-105.380300000000,-105.709100000000,-106.104900000000,-106.570000000000,-107.106200000000,-107.715700000000,-108.400000000000,-109.160800000000,-109.999500000000,-110.917400000000,-111.915800000000,-112.995700000000,-114.158100000000,-115.404000000000,-116.734200000000,-118.149400000000,-119.650300000000,-121.237400000000,-122.911400000000,-124.672700000000,-126.521800000000,-128.459000000000,-130.484600000000,-132.599100000000,-134.802600000000,-137.095400000000,-139.477600000000,-141.949500000000,-144.511200000000,-147.162700000000,-149.904200000000,-152.735800000000,-155.657500000000,-158.669400000000,-161.771400000000,-164.963600000000,-168.246100000000,-171.618600000000,-175.081300000000,-178.634100000000,-182.277000000000,-186.009900000000,-189.832700000000,-193.745300000000;0,-7.29285600000000,-14.5340200000000,-21.6909100000000,-28.7324100000000,-35.6292500000000,-42.3541900000000,-48.8823100000000,-55.1912400000000,-61.2612200000000,-67.0752900000000,-72.6193200000000,-77.8819900000000,-82.8548000000000,-87.5320000000000,-91.9104700000000,-95.9895600000000,-99.7709000000000,-103.258400000000,-106.457700000000,-109.376300000000,-112.023100000000,-114.408400000000,-116.543500000000,-118.440600000000,-120.112600000000,-121.572800000000,-122.834800000000,-123.912600000000,-124.820200000000,-125.571200000000,-126.179500000000,-126.658300000000,-127.021000000000,-127.280000000000,-127.447700000000,-127.536000000000,-127.556100000000,-127.518800000000,-127.434400000000,-127.312700000000,-127.162800000000,-126.993600000000,-126.813100000000,-126.629200000000,-126.449100000000,-126.279500000000,-126.126800000000,-125.996900000000,-125.895400000000,-125.827300000000,-125.797500000000,-125.810300000000,-125.870000000000,-125.980200000000,-126.144600000000,-126.366400000000,-126.648500000000,-126.993900000000,-127.405000000000,-127.884200000000,-128.433700000000,-129.055500000000,-129.751300000000,-130.522900000000,-131.371900000000,-132.299600000000,-133.307300000000,-134.396100000000,-135.567200000000,-136.821500000000,-138.160000000000,-139.583300000000,-141.092200000000,-142.687400000000,-144.369500000000,-146.138900000000,-147.996300000000,-149.941900000000,-151.976200000000,-154.099500000000,-156.312100000000,-158.614200000000,-161.006100000000,-163.487900000000,-166.059900000000,-168.722100000000,-171.474600000000,-174.317600000000,-177.251100000000,-180.275200000000,-183.389900000000,-186.595200000000,-189.891200000000,-193.277700000000,-196.754900000000,-200.322600000000,-203.980900000000,-207.729600000000,-211.568700000000;0,-8.14331600000000,-16.2308600000000,-24.2286000000000,-32.1039700000000,-39.8262700000000,-47.3668500000000,-54.6993800000000,-61.8000300000000,-68.6476200000000,-75.2238000000000,-81.5130500000000,-87.5026900000000,-93.1829600000000,-98.5468700000000,-103.590200000000,-108.311200000000,-112.710800000000,-116.791900000000,-120.559800000000,-124.021200000000,-127.185100000000,-130.061200000000,-132.661100000000,-134.996800000000,-137.081600000000,-138.929200000000,-140.553600000000,-141.969300000000,-143.190900000000,-144.232800000000,-145.109500000000,-145.835300000000,-146.424100000000,-146.889400000000,-147.244300000000,-147.501700000000,-147.673600000000,-147.771700000000,-147.807300000000,-147.790800000000,-147.732400000000,-147.641600000000,-147.527300000000,-147.398100000000,-147.261800000000,-147.126100000000,-146.997800000000,-146.883500000000,-146.789400000000,-146.721100000000,-146.684000000000,-146.683000000000,-146.722800000000,-146.807500000000,-146.941100000000,-147.127300000000,-147.369600000000,-147.671000000000,-148.034300000000,-148.462400000000,-148.957600000000,-149.522300000000,-150.158500000000,-150.868100000000,-151.652800000000,-152.514500000000,-153.454300000000,-154.473800000000,-155.574100000000,-156.756500000000,-158.021900000000,-159.371200000000,-160.805300000000,-162.325100000000,-163.931000000000,-165.623900000000,-167.404300000000,-169.272600000000,-171.229400000000,-173.274900000000,-175.409700000000,-177.634000000000,-179.948100000000,-182.352200000000,-184.846500000000,-187.431200000000,-190.106600000000,-192.872600000000,-195.729400000000,-198.677100000000,-201.715700000000,-204.845300000000,-208.065900000000,-211.377600000000,-214.780200000000,-218.273900000000,-221.858600000000,-225.534200000000,-229.300700000000;0,-8.94577100000000,-17.8314600000000,-26.6220600000000,-35.2841000000000,-43.7859100000000,-52.0978600000000,-60.1925900000000,-68.0451700000000,-75.6333500000000,-82.9375500000000,-89.9410300000000,-96.6299000000000,-102.993200000000,-109.022700000000,-114.713000000000,-120.061300000000,-125.067400000000,-129.733500000000,-134.063800000000,-138.064800000000,-141.744500000000,-145.112600000000,-148.180200000000,-150.959400000000,-153.463400000000,-155.706000000000,-157.701600000000,-159.465000000000,-161.011200000000,-162.355300000000,-163.512400000000,-164.497300000000,-165.324900000000,-166.009100000000,-166.564300000000,-167.003600000000,-167.340300000000,-167.586600000000,-167.754800000000,-167.856100000000,-167.901400000000,-167.901000000000,-167.864600000000,-167.801500000000,-167.720300000000,-167.629200000000,-167.535800000000,-167.447300000000,-167.370400000000,-167.311500000000,-167.276300000000,-167.270400000000,-167.298700000000,-167.366100000000,-167.476900000000,-167.635300000000,-167.844900000000,-168.109300000000,-168.431800000000,-168.815200000000,-169.262400000000,-169.775900000000,-170.358100000000,-171.011200000000,-171.737100000000,-172.537600000000,-173.414500000000,-174.369300000000,-175.403400000000,-176.518000000000,-177.714400000000,-178.993700000000,-180.356800000000,-181.804600000000,-183.337900000000,-184.957500000000,-186.664000000000,-188.458000000000,-190.340100000000,-192.310700000000,-194.370300000000,-196.519200000000,-198.757900000000,-201.086500000000,-203.505300000000,-206.014700000000,-208.614700000000,-211.305600000000,-214.087500000000,-216.960400000000,-219.924600000000,-222.980000000000,-226.126700000000,-229.364800000000,-232.694200000000,-236.115000000000,-239.627200000000,-243.230700000000,-246.925400000000;0,-9.69696400000000,-19.3292800000000,-28.8615500000000,-38.2598700000000,-47.4921200000000,-56.5281500000000,-65.3399700000000,-73.9019600000000,-82.1910100000000,-90.1866300000000,-97.8710500000000,-105.229200000000,-112.249100000000,-118.921200000000,-125.238900000000,-131.198300000000,-136.798100000000,-142.039300000000,-146.925300000000,-151.461600000000,-155.655500000000,-159.516200000000,-163.054300000000,-166.281600000000,-169.211000000000,-171.856300000000,-174.232100000000,-176.353300000000,-178.235200000000,-179.893200000000,-181.343100000000,-182.600000000000,-183.679400000000,-184.596200000000,-185.365000000000,-186,-186.515000000000,-186.923200000000,-187.237500000000,-187.469800000000,-187.631800000000,-187.734600000000,-187.788700000000,-187.803900000000,-187.789700000000,-187.754800000000,-187.707600000000,-187.655800000000,-187.606800000000,-187.567400000000,-187.544000000000,-187.542600000000,-187.568800000000,-187.627800000000,-187.724300000000,-187.863000000000,-188.047900000000,-188.282900000000,-188.571700000000,-188.917500000000,-189.323400000000,-189.792300000000,-190.326800000000,-190.929300000000,-191.602000000000,-192.347000000000,-193.166200000000,-194.061200000000,-195.033900000000,-196.085500000000,-197.217400000000,-198.430800000000,-199.726800000000,-201.106600000000,-202.571000000000,-204.120800000000,-205.756800000000,-207.479800000000,-209.290300000000,-211.188900000000,-213.176100000000,-215.252500000000,-217.418200000000,-219.673800000000,-222.019500000000,-224.455700000000,-226.982600000000,-229.600300000000,-232.309100000000,-235.109200000000,-238.000500000000,-240.983300000000,-244.057600000000,-247.223500000000,-250.481000000000,-253.830100000000,-257.270900000000,-260.803400000000,-264.427300000000];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
                    SAData = [0;0.101010100000000;0.202020200000000;0.303030300000000;0.404040400000000;0.505050500000000;0.606060600000000;0.707070700000000;0.808080800000000;0.909090900000000];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
                    
                else
                    
                end
                
                S = size(Normal);
                
                Coeff = zeros(S);
                SA = zeros(S);
                
                for i = 1:S(2)
                    
                    
                    for j = 1:S(1)
                        
                        I1 = find(Fz >= Normal(j,i),1,'first');
                        I2 = find(Fz <= Normal(j,i),1,'last');
                        
                        if I1
                        else
                            I1 = I2;
                        end
                        
                        if I2
                        else
                            I2 = I1;
                        end
                        
                        [CoeffA] = max(abs(Grip(:,I1)));
                        [CoeffB] = max(abs(Grip(:,I2)));
                        
                        Coeff(j,i) = ((Normal(j,i) - Fz(I1))/(Fz(I2) - Fz(I1)))*(CoeffB - CoeffA) + CoeffA;
                        
                        diff1 = Fz(I1) - Normal(j,i);
                        diff2 = Fz(I2) - Normal(j,i);
                        
                        if abs(diff1) > abs(diff2)
                            I = I2;
                        else
                            I = I1;
                        end
                        
                        [~, J] = max(abs(Grip(:,I)));
                        SA(j,i) = SAData(J);
                        
                    end
                end
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Trackslist
        function TrackslistValueChanged(app, event)
%             value = app.Trackslist.Value;
        end

        % Button pushed function: RunsimulationButton
        function RunsimulationButtonPushed(app, event)

            app.car=carbuilder(app);
            value = app.Trackslist.Value;
            if strcmp(value,"FSG")
                app.track = FSG2013;
            elseif strcmp(value,"FSUK")
                app.track = FSAELincoln2013;
            elseif strcmp(value,"FSAE Michigan")
                app.track = FSAEMichigan2015;
            end
            app.Tele = Simulate(app.car,app.track);
            app.TotalLaptime.Value =app.Tele.Results{1, 15};
            app.AverageLatGs.Value = app.Tele.Results{1, 16};
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            listval_x = app.XaxisListBox.Value;
            if strcmp(listval_x,"Lat G's")
                x = app.Tele.LapDataStructure.LatG;
                app.postpro.XLabel.String = 'Lateral Gs';
            elseif strcmp(listval_x,"Long G's")
                x = app.Tele.LapDataStructure.LongG;
                app.postpro.XLabel.String = 'Longitudinal Gs';
            elseif strcmp(listval_x,"Position")
                x = app.Tele.LapDataStructure.Position;
                app.postpro.XLabel.String = 'Position';
            elseif strcmp(listval_x,"Motor Power")
                x = app.Tele.LapDataStructure.MotorPower;
                app.postpro.XLabel.String = 'Motor Power';
            elseif strcmp(listval_x,"Motor torque")
                x = app.Tele.LapDataStructure.MotorTorque;
                app.postpro.XLabel.String = 'Motor torque';
            elseif strcmp(listval_x,"Braking Torque")
                x = app.Tele.LapDataStructure.BrakingTorque;
                app.postpro.XLabel.String = 'Braking torque';
            elseif strcmp(listval_x,"Battery Current")
                x = app.Tele.LapDataStructure.BatteryCurrent;
                app.postpro.XLabel.String = 'Battery current';
            elseif strcmp(listval_x,"Battery Voltage")
                x = app.Tele.LapDataStructure.BatteryVoltage;
                app.postpro.XLabel.String = 'Battery Voltage';
            elseif strcmp(listval_x,"Battery Power")
                x = app.Tele.LapDataStructure.BatteryPower;
                app.postpro.XLabel.String = 'Battery Power';
            elseif strcmp(listval_x,"Time")
                x = app.Tele.LapDataStructure.Time;
                app.postpro.XLabel.String = 'Time';
            elseif strcmp(listval_x,"Velocity")
                x = app.Tele.LapDataStructure.Velocity;
                app.postpro.XLabel.String = 'Velocity';
            else
                warning('select x-axis')
            end
            listval_y = app.YaxisListBox.Value;
            if strcmp(listval_y,"Lat G's")
                y = app.Tele.LapDataStructure.LatG;
                app.postpro.YLabel.String = 'Lateral Gs';
            elseif strcmp(listval_y,"Long G's")
                y = app.Tele.LapDataStructure.LongG;
                app.postpro.YLabel.String = 'Longitudinal Gs';
            elseif strcmp(listval_y,"Time")
                y = app.Tele.LapDataStructure.Time;
                app.postpro.YLabel.String = 'Time';
            elseif strcmp(listval_y,"Position")
                y = app.Tele.LapDataStructure.Position;
                app.postpro.YLabel.String = 'Position';
            elseif strcmp(listval_y,"Motor Power")
                y = app.Tele.LapDataStructure.MotorPower;
                app.postpro.YLabel.String = 'Motor Power';
            elseif strcmp(listval_y,"Motor torque")
                y = app.Tele.LapDataStructure.MotorTorque;
                app.postpro.YLabel.String = 'Motor torque';
            elseif strcmp(listval_y,"Braking Torque")
                y = app.Tele.LapDataStructure.BrakingTorque;
                app.postpro.YLabel.String = 'Braking torque';
            elseif strcmp(listval_y,"Battery Current")
                y = app.Tele.LapDataStructure.BatteryCurrent;
                app.postpro.YLabel.String = 'Battery current';
            elseif strcmp(listval_y,"Battery Voltage")
                y = app.Tele.LapDataStructure.BatteryVoltage;
                app.postpro.YLabel.String = 'Battery Voltage';
            elseif strcmp(listval_y,"Battery Power")
                y = app.Tele.LapDataStructure.BatteryPower;
                app.postpro.YLabel.String = 'Battery Power';
            elseif strcmp(listval_y,"Time")
                y = app.Tele.LapDataStructure.Time;
                app.postpro.YLabel.String = 'Time';
            elseif strcmp(listval_y,"Velocity")
                y = app.Tele.LapDataStructure.Velocity;
                app.postpro.YLabel.String = 'Velocity';
            else
                warning('select y-axis')
            end
            plot(app.postpro,x,y,'linewidth',2)
            
        end

        % Button pushed function: loadmap
        function loadmapButtonPushed(app, event)
                        plot(app.motormap,OutputCurve(:,1),OutputCurve(:,2),'linewidth',2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CFSLaptimeSimulatorUIFigure = uifigure('Visible', 'off');
            app.CFSLaptimeSimulatorUIFigure.Name = 'CFS Laptime Simulator';
            app.TrackselectionPanel = uipanel(app.CFSLaptimeSimulatorUIFigure);
            app.TrackselectionPanel.Title = 'Track selection';
            app.TracksDropDownLabel = uilabel(app.TrackselectionPanel);
            app.TracksDropDownLabel.Text = 'Tracks';
            app.Trackslist = uidropdown(app.TrackselectionPanel);
            app.Trackslist.Items = {'FSG', 'FSUK', 'FSAE Michigan'};
            app.Trackslist.ValueChangedFcn = createCallbackFcn(app, @TrackslistValueChanged, true);
            app.Trackslist.Value = 'FSG';
            app.Loadtrack = uibutton(app.TrackselectionPanel, 'push');
            app.Loadtrack.Text = 'Load';
            app.trackmap = uiaxes(app.TrackselectionPanel);
            title(app.trackmap, 'Track layout')
            xlabel(app.trackmap, 'X')
            ylabel(app.trackmap, 'Y')
            app.VehicleparametersPanel = uipanel(app.CFSLaptimeSimulatorUIFigure);
            app.VehicleparametersPanel.Title = 'Vehicle parameters';
            app.TabGroup = uitabgroup(app.VehicleparametersPanel);
            app.ChassisTab = uitab(app.TabGroup);
            app.ChassisTab.Title = 'Chassis';
            app.WheelbaseEditFieldLabel = uilabel(app.ChassisTab);
            app.WheelbaseEditFieldLabel.Text = 'Wheelbase';
            app.Wheelbase = uieditfield(app.ChassisTab, 'numeric');
            app.Wheelbase.Value = 60.5;
            app.TrackwidthfrontEditFieldLabel = uilabel(app.ChassisTab);
            app.TrackwidthfrontEditFieldLabel.Text = 'Trackwidth  front';
            app.Trackwidthfront = uieditfield(app.ChassisTab, 'numeric');
            app.Trackwidthfront.Value = 49;
            app.TrackwidthRearEditFieldLabel = uilabel(app.ChassisTab);
            app.TrackwidthRearEditFieldLabel.Text = 'Trackwidth  Rear';
            app.TrackwidthRear = uieditfield(app.ChassisTab, 'numeric');
            app.TrackwidthRear.Value = 49;
            app.VehicleCOGEditFieldLabel = uilabel(app.ChassisTab);
            app.VehicleCOGEditFieldLabel.Text = 'Vehicle COG';
            app.VehicleCOG_x = uieditfield(app.ChassisTab, 'numeric');
            app.VehicleCOG_x.Value = 27.225;
            app.CarweightEditFieldLabel = uilabel(app.ChassisTab);
            app.CarweightEditFieldLabel.Text = 'Car weight';
            app.Carweight = uieditfield(app.ChassisTab, 'numeric');
            app.Carweight.Value = 560;
            app.DriversweightEditFieldLabel = uilabel(app.ChassisTab);
            app.DriversweightEditFieldLabel.Text = 'Drivers weight';
            app.Driversweight = uieditfield(app.ChassisTab, 'numeric');
            app.Driversweight.Value = 70;
            app.VehicleCOG_y = uieditfield(app.ChassisTab, 'numeric');
            app.VehicleCOG_z = uieditfield(app.ChassisTab, 'numeric');
            app.VehicleCOG_z.Value = 9.14;
            app.DriverCOG_y = uieditfield(app.ChassisTab, 'numeric');
            app.DriverCOG_z = uieditfield(app.ChassisTab, 'numeric');
            app.DriverCOG_z.Value = 9.1428;
            app.DriverCOGEditFieldLabel = uilabel(app.ChassisTab);
            app.DriverCOGEditFieldLabel.Text = 'Driver COG';
            app.DriverCOG_x = uieditfield(app.ChassisTab, 'numeric');
            app.DriverCOG_x.Value = 27.225;
            app.SuspensionTab = uitab(app.TabGroup);
            app.SuspensionTab.Title = 'Suspension';
            app.SpringrateEditFieldLabel = uilabel(app.SuspensionTab);
            app.SpringrateEditFieldLabel.Text = 'Spring rate';
            app.Springrate_front = uieditfield(app.SuspensionTab, 'numeric');
            app.Springrate_front.Value = 300;
            app.Springrate_rear = uieditfield(app.SuspensionTab, 'numeric');
            app.Springrate_rear.Value = 300;
            app.FrontLabel = uilabel(app.SuspensionTab);
            app.FrontLabel.Text = 'Front';
            app.RearLabel = uilabel(app.SuspensionTab);
            app.RearLabel.Text = 'Rear';
            app.Unsprungweight_rear = uieditfield(app.SuspensionTab, 'numeric');
            app.Unsprungweight_rear.Value = 41;
            app.UnsprungweightEditFieldLabel = uilabel(app.SuspensionTab);
            app.UnsprungweightEditFieldLabel.Text = 'Unsprung weight';
            app.Unsprungweight_front = uieditfield(app.SuspensionTab, 'numeric');
            app.Unsprungweight_front.Value = 41;
            app.RearLabel_3 = uilabel(app.SuspensionTab);
            app.RearLabel_3.Text = 'Rear';
            app.FrontLabel_3 = uilabel(app.SuspensionTab);
            app.FrontLabel_3.Text = 'Front';
            app.UnsprungHeight_rear = uieditfield(app.SuspensionTab, 'numeric');
            app.UnsprungHeight_rear.Value = 9;
            app.RearLabel_4 = uilabel(app.SuspensionTab);
            app.RearLabel_4.Text = 'Rear';
            app.FrontLabel_4 = uilabel(app.SuspensionTab);
            app.FrontLabel_4.Text = 'Front';
            app.UnsprungHeightEditFieldLabel = uilabel(app.SuspensionTab);
            app.UnsprungHeightEditFieldLabel.Text = 'Unsprung Height';
            app.UnsprungHeight_front = uieditfield(app.SuspensionTab, 'numeric');
            app.UnsprungHeight_front.Value = 9;
            app.Rollcenter_rear = uieditfield(app.SuspensionTab, 'numeric');
            app.Rollcenter_rear.Value = 1.7;
            app.RearLabel_5 = uilabel(app.SuspensionTab);
            app.RearLabel_5.Text = 'Rear';
            app.FrontLabel_5 = uilabel(app.SuspensionTab);
            app.FrontLabel_5.Text = 'Front';
            app.RollcenterEditFieldLabel = uilabel(app.SuspensionTab);
            app.RollcenterEditFieldLabel.Text = 'Roll center';
            app.Rollcenter_front = uieditfield(app.SuspensionTab, 'numeric');
            app.Rollcenter_front.Value = 0.48;
            app.Pitchcenter_rear = uieditfield(app.SuspensionTab, 'numeric');
            app.Pitchcenter_rear.Value = 30;
            app.RearLabel_6 = uilabel(app.SuspensionTab);
            app.RearLabel_6.Text = 'Rear';
            app.FrontLabel_6 = uilabel(app.SuspensionTab);
            app.FrontLabel_6.Text = 'Front';
            app.PitchcenterEditFieldLabel = uilabel(app.SuspensionTab);
            app.PitchcenterEditFieldLabel.Text = 'Pitch center';
            app.Pitchcenter_front = uieditfield(app.SuspensionTab, 'numeric');
            app.Pitchcenter_front.Value = 10;
            app.Antirollbarstiffness_rear = uieditfield(app.SuspensionTab, 'numeric');
            app.FrontLabel_7 = uilabel(app.SuspensionTab);
            app.FrontLabel_7.Text = 'Front';
            app.RearLabel_7 = uilabel(app.SuspensionTab);
            app.RearLabel_7.Text = 'Rear';
            app.AntirollbarstiffnessEditField_7Label = uilabel(app.SuspensionTab);
            app.AntirollbarstiffnessEditField_7Label.Text = 'Antiroll bar stiffness';
            app.Antirollbarstiffness_front = uieditfield(app.SuspensionTab, 'numeric');
            app.Antirollbarstiffness_front.Value = 300;
            app.BrakeinertiaEditFieldLabel = uilabel(app.SuspensionTab);
            app.BrakeinertiaEditFieldLabel.Text = 'Brake inertia';
            app.Brakeinertia = uieditfield(app.SuspensionTab, 'numeric');
            app.Brakeinertia.Value = 0.967;
            app.TireTab = uitab(app.TabGroup);
            app.TireTab.Title = 'Tire';
            app.TireradiusEditFieldLabel = uilabel(app.TireTab);
            app.TireradiusEditFieldLabel.Text = 'Tire radius';
            app.Tireradius = uieditfield(app.TireTab, 'numeric');
            app.Tireradius.Value = 10.5;
            app.TireStiffnessEditFieldLabel = uilabel(app.TireTab);
            app.TireStiffnessEditFieldLabel.Text = 'Tire Stiffness';
            app.TireStiffness = uieditfield(app.TireTab, 'numeric');
            app.TireStiffness.Value = 900;
            app.RollingresistanceEditFieldLabel = uilabel(app.TireTab);
            app.RollingresistanceEditFieldLabel.Text = 'Rolling resistance';
            app.Rollingresistance = uieditfield(app.TireTab, 'numeric');
            app.Rollingresistance.Value = 0.03;
            app.RotationalInertiaEditFieldLabel = uilabel(app.TireTab);
            app.RotationalInertiaEditFieldLabel.Text = 'Rotational Inertia';
            app.RotationalInertia = uieditfield(app.TireTab, 'numeric');
            app.RotationalInertia.Value = 120.628;
            app.GripfactorEditFieldLabel = uilabel(app.TireTab);
            app.GripfactorEditFieldLabel.Text = 'Grip factor';
            app.Gripfactor = uieditfield(app.TireTab, 'numeric');
            app.Gripfactor.Value = 0.88;
            app.TireLabel = uilabel(app.TireTab);
            app.TireLabel.Text = 'Tire ';
            app.TiresdataButtonGroup = uibuttongroup(app.TireTab);
            app.TiresdataButtonGroup.Title = 'Tires data';
            app.Hoosier13Button = uiradiobutton(app.TiresdataButtonGroup);
            app.Hoosier13Button.Text = 'Hoosier 13"';
            app.Hoosier13Button.Value = true;
            app.Hoosier10Button = uiradiobutton(app.TiresdataButtonGroup);
            app.Hoosier10Button.Text = 'Hoosier 10"';
            app.AeroTab = uitab(app.TabGroup);
            app.AeroTab.Title = 'Aero';
            app.DragcoefficientEditFieldLabel = uilabel(app.AeroTab);
            app.DragcoefficientEditFieldLabel.Text = 'Drag coefficient';
            app.Dragcoefficient = uieditfield(app.AeroTab, 'numeric');
            app.Dragcoefficient.Value = 0.7;
            app.LiftcoefficientEditFieldLabel = uilabel(app.AeroTab);
            app.LiftcoefficientEditFieldLabel.Text = 'Lift coefficient';
            app.Liftcoefficient = uieditfield(app.AeroTab, 'numeric');
            app.Liftcoefficient.Value = -1.2;
            app.CrosssectionareaEditFieldLabel = uilabel(app.AeroTab);
            app.CrosssectionareaEditFieldLabel.Text = 'Cross section area';
            app.Crosssectionarea = uieditfield(app.AeroTab, 'numeric');
            app.Crosssectionarea.Value = 2015;
            app.AirdensityEditFieldLabel = uilabel(app.AeroTab);
            app.AirdensityEditFieldLabel.Text = 'Air density';
            app.Airdensity = uieditfield(app.AeroTab, 'numeric');
            app.Airdensity.Value = 0.0023;
            app.CenterofpressureEditFieldLabel = uilabel(app.AeroTab);
            app.CenterofpressureEditFieldLabel.Text = 'Center of pressure';
            app.COP_y = uieditfield(app.AeroTab, 'numeric');
            app.COP_x = uieditfield(app.AeroTab, 'numeric');
            app.COP_x.Value = 31.7;
            app.COP_z = uieditfield(app.AeroTab, 'numeric');
            app.COP_z.Value = 10;
            app.PowertrainTab = uitab(app.TabGroup);
            app.PowertrainTab.Title = 'Powertrain';
            app.TabGroup2 = uitabgroup(app.PowertrainTab);
            app.MotorTab = uitab(app.TabGroup2);
            app.MotorTab.Title = 'Motor';
            app.EfficiencyEditFieldLabel = uilabel(app.MotorTab);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency';
            app.motorEfficiency = uieditfield(app.MotorTab, 'numeric');
            app.motorEfficiency.Value = 0.95;
            app.PeakTorqueEditFieldLabel = uilabel(app.MotorTab);
            app.PeakTorqueEditFieldLabel.Text = 'Peak Torque';
            app.BatteryPeakTorque = uieditfield(app.MotorTab, 'numeric');
            app.BatteryPeakTorque.Value = 240;
            app.RPMLimitEditFieldLabel = uilabel(app.MotorTab);
            app.RPMLimitEditFieldLabel.Text = 'RPM Limit';
            app.BatteryRPMLimit = uieditfield(app.MotorTab, 'numeric');
            app.BatteryRPMLimit.Value = 5500;
            app.ButtonGroup = uibuttongroup(app.MotorTab);
            app.WDButton = uiradiobutton(app.ButtonGroup);
            app.WDButton.Text = '2WD';
            app.WDButton.Value = true;
            app.WDButton_2 = uiradiobutton(app.ButtonGroup);
            app.WDButton_2.Text = '4WD';
            app.motormap = uiaxes(app.MotorTab);
            title(app.motormap, 'Motor map')
            xlabel(app.motormap, 'RPM')
            ylabel(app.motormap, 'Torque (Nm)')
            app.loadmap = uibutton(app.MotorTab, 'push');
            app.loadmap.ButtonPushedFcn = createCallbackFcn(app, @loadmapButtonPushed, true);
            app.loadmap.Text = 'Load';
            app.BatteryTab = uitab(app.TabGroup2);
            app.BatteryTab.Title = 'Battery';
            app.CapacityEditFieldLabel = uilabel(app.BatteryTab);
            app.CapacityEditFieldLabel.Text = 'Capacity';
            app.BatteryCapacity = uieditfield(app.BatteryTab, 'numeric');
            app.BatteryCapacity.Value = 6.5;
            app.NominalVoltageEditFieldLabel = uilabel(app.BatteryTab);
            app.NominalVoltageEditFieldLabel.Text = 'Nominal Voltage';
            app.BatteryNominalVoltage = uieditfield(app.BatteryTab, 'numeric');
            app.BatteryNominalVoltage.Value = 242;
            app.ResistanceEditFieldLabel = uilabel(app.BatteryTab);
            app.ResistanceEditFieldLabel.Text = 'Resistance';
            app.BatteryResistance = uieditfield(app.BatteryTab, 'numeric');
            app.BatteryResistance.Value = 0.16;
            app.EfficiencyEditField_2Label = uilabel(app.BatteryTab);
            app.EfficiencyEditField_2Label.Text = 'Efficiency';
            app.BatteryEfficiency = uieditfield(app.BatteryTab, 'numeric');
            app.BatteryEfficiency.Value = 0.9;
            app.DrivelineTab = uitab(app.TabGroup2);
            app.DrivelineTab.Title = 'Driveline';
            app.EfficiencyEditField_3Label = uilabel(app.DrivelineTab);
            app.EfficiencyEditField_3Label.Text = 'Efficiency';
            app.DrivelineEfficiency = uieditfield(app.DrivelineTab, 'numeric');
            app.DrivelineEfficiency.Value = 0.9;
            app.DrivelineinertiaEditFieldLabel = uilabel(app.DrivelineTab);
            app.DrivelineinertiaEditFieldLabel.Text = 'Driveline inertia';
            app.Drivelineinertia = uieditfield(app.DrivelineTab, 'numeric');
            app.Drivelineinertia.Value = 20;
            app.GearratioEditFieldLabel = uilabel(app.DrivelineTab);
            app.GearratioEditFieldLabel.Text = 'Gear ratio';
            app.Gearratio = uieditfield(app.DrivelineTab, 'numeric');
            app.Gearratio.Value = 3.5;
            app.FinaldriveratioEditFieldLabel = uilabel(app.DrivelineTab);
            app.FinaldriveratioEditFieldLabel.Text = 'Final drive ratio';
            app.Finaldriveratio = uieditfield(app.DrivelineTab, 'numeric');
            app.Finaldriveratio.Value = 1;
            app.PostprocessorPanel = uipanel(app.CFSLaptimeSimulatorUIFigure);
            app.PostprocessorPanel.Title = 'Post processor';
            app.postpro = uiaxes(app.PostprocessorPanel);
            title(app.postpro, '')
            xlabel(app.postpro, 'X')
            ylabel(app.postpro, 'Y')
            app.XaxisListBoxLabel = uilabel(app.PostprocessorPanel);
            app.XaxisListBoxLabel.Text = 'X-axis';
            app.XaxisListBox = uilistbox(app.PostprocessorPanel);
            app.XaxisListBox.Items = {'Lat G''s', 'Position', 'Motor Power', 'Motor torque', 'Braking Torque', 'Battery Current', 'Battery Voltage', 'Battery Power', 'Long G''s', 'Time', 'Velocity'};
            app.XaxisListBox.Value = 'Lat G''s';
            app.PlotButton = uibutton(app.PostprocessorPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.YaxisListBoxLabel = uilabel(app.PostprocessorPanel);
            app.YaxisListBoxLabel.Text = 'Y-axis';
            app.YaxisListBox = uilistbox(app.PostprocessorPanel);
            app.YaxisListBox.Items = {'Lat G''s', 'Position', 'Motor Power', 'Motor torque', 'Braking Torque', 'Battery Current', 'Battery Voltage', 'Battery Power', 'Long G''s', 'Time', 'Velocity'};
            app.YaxisListBox.Value = 'Lat G''s';
            app.RunsimulationButton = uibutton(app.CFSLaptimeSimulatorUIFigure, 'push');
            app.RunsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunsimulationButtonPushed, true);
            app.RunsimulationButton.Text = 'Run simulation';
            app.TotalLaptimeEditFieldLabel = uilabel(app.CFSLaptimeSimulatorUIFigure);
            app.TotalLaptimeEditFieldLabel.Text = 'Total Laptime';
            app.TotalLaptime = uieditfield(app.CFSLaptimeSimulatorUIFigure, 'numeric');
            app.AverageLatGsEditFieldLabel = uilabel(app.CFSLaptimeSimulatorUIFigure);
            app.AverageLatGsEditFieldLabel.Text = 'Average Lat G''s';
            app.AverageLatGs = uieditfield(app.CFSLaptimeSimulatorUIFigure, 'numeric');
            app.AverageLongGsEditFieldLabel = uilabel(app.CFSLaptimeSimulatorUIFigure);
            app.AverageLongGsEditFieldLabel.Text = 'Average LongG''s';
            app.AverageLongGs = uieditfield(app.CFSLaptimeSimulatorUIFigure, 'numeric');
            app.TotalPointsEditFieldLabel = uilabel(app.CFSLaptimeSimulatorUIFigure);
            app.TotalPointsEditFieldLabel.Text = 'Total Points';
            app.TotalPoints = uieditfield(app.CFSLaptimeSimulatorUIFigure, 'numeric');
            app.SaveButton = uibutton(app.CFSLaptimeSimulatorUIFigure, 'state');
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.CFSLaptimeSimulatorUIFigure, 'state');
            app.LoadButton.Text = 'Load';
            app.LaptimesimulatorV10Button = uibutton(app.CFSLaptimeSimulatorUIFigure, 'push');
            app.LaptimesimulatorV10Button.Icon = 'CFS-Logo.png';
            app.LaptimesimulatorV10Button.Text = 'Laptime simulator V1.0';
            % Show the figure after all components are created
            app.CFSLaptimeSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lap_sim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CFSLaptimeSimulatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CFSLaptimeSimulatorUIFigure)
        end
    end
end